classdef electric_field < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        interfaz             matlab.ui.Figure
        numerodecargas       matlab.ui.control.NumericEditField
        NumerodecargasLabel  matlab.ui.control.Label
        tablacargas          matlab.ui.control.Table
    end

    
    methods (Access = public)
        
        %Función de campo eléctrico por superposición
        function [E] = ElectricField()
            posP = [2,4,5];
            posQ = [5,6,2];
            magQ = 4.6;
            Eo = 8.854187817*10^(-12);
            k = 1/(4*pi*Eo);
            
            x = posP(1) - posQ(1);
            y = posP(2) - posQ(2);
            z = posP(3) - posQ(3);
            
            R = sqrt(x^2+y^2+z^2);
            U = [x/R, y/R, z/R];
            
            E = ((k*magQ)/(R^2))*U;
           
            CampoElectrico = E;
            display(CampoElectrico);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.tablacargas.Data = cell(2,3);
        end

        % Cell edit callback: tablacargas
        function tablacargasCellEdit(app, event)
            
        end

        % Value changed function: numerodecargas
        function numerodecargasValueChanged(app, event)
            cantidad_cargas = app.numerodecargas.Value;
            app.tablacargas.Data = cell(cantidad_cargas,3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.interfaz = uifigure('Visible', 'off');
            app.interfaz.Name = 'MATLAB App';
            app.tablacargas = uitable(app.interfaz);
            app.tablacargas.ColumnName = {'Carga'; 'x'; 'y'};
            app.tablacargas.RowName = {};
            app.tablacargas.ColumnEditable = true;
            app.tablacargas.CellEditCallback = createCallbackFcn(app, @tablacargasCellEdit, true);
            app.NumerodecargasLabel = uilabel(app.interfaz);
            app.NumerodecargasLabel.Text = 'Numero de cargas';
            app.numerodecargas = uieditfield(app.interfaz, 'numeric');
            app.numerodecargas.Limits = [2 Inf];
            app.numerodecargas.RoundFractionalValues = 'on';
            app.numerodecargas.ValueChangedFcn = createCallbackFcn(app, @numerodecargasValueChanged, true);
            app.numerodecargas.Value = 2;
            % Show the figure after all components are created
            app.interfaz.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = electric_field

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.interfaz)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.interfaz)
        end
    end
end